classdef experiment_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TimeRemainingTextArea       matlab.ui.control.TextArea
        TimeRemainingTextAreaLabel  matlab.ui.control.Label
        DampingSlider               matlab.ui.control.Slider
        DampingSliderLabel          matlab.ui.control.Label
        BeginPracticeButton         matlab.ui.control.Button
        BeginTestButton             matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        t1 % timer for continuous gui refresh
        
        event_start %variable to store time of test begin
        move_timer % tracks time between star moves 
        practice_interval % interval between movements of star
        event_duration % used to tell machine how long to run test for
        countdown % tracks size for countdowns
        app_state % tracks whether a test is actively occuring false for idle state
        next_state % tracks next state for program
        
        dt % time step size
        state_data % Position Data that test spits out
        time_data % Time Data that test spits out
        q % state variables
        m % connection to phone for sensor data
        index % for iteration through preallocated state data structure
        
        % debugging tools
        time_benchmark % used to compare change impacts

        % plotting properties
        ptrail % updating plot trail more efficiently
        pball % updating plot ball more efficiently
        pstar % updating star more effectively
        ptext % countdown text
        ball_mass % mass of ball

        tab_orient
        
    end
    
    methods (Access = private)
        
        function qdot = dynamics(app,q, theta, phi, thetadot, phidot, phiddot, thetaddot)
            % https://openresearch.lsbu.ac.uk/download/686833395ced713bba681ef2035f99f2b17c4d98ecd8d45ae8d4959b259a55ab/253688/RollTip%28v5%29.pdf
            g = 9.81;
            r = .1;
            
            % Extract variables
            x = q(1); 
            y = q(2); 
            xdot = q(4); 
            ydot = q(5); 
            gammadot = q(6); 
            
            % Arbitrary damping to improve controlability
            b = app.DampingSlider.Value; 
            
            % Calculate dynamics
            termA = 1/5 * [
                    7*r*cos(theta) + 5*y*sin(theta), 0; 
                    5*x*sin(theta), 7*r;
                    -2*r*sin(theta), 0
                ] * [phiddot; thetaddot];
            
            termB = 1/5 * [7,0,0;0,-7,0;0,0,2*r];
            termC = [-x, 0;
                y*sin(theta)^2 + r*cos(theta)*sin(theta), y;
                0,0] * [phidot^2; thetadot^2];
            
            termD = 1/5* [10*y*cos(theta) - 12*r*sin(theta), 0, 12*sin(theta), 0, 0, -2*r; ...
                0, 12*sin(theta), 0, 2*r*cos(theta), 0, 0;...
                -2*r*cos(theta), 0, 2*cos(theta), 0, 2, 0] * ...
                [phidot*thetadot; phidot*xdot; phidot*ydot; phidot*gammadot; thetadot*xdot; thetadot*ydot]; 
            
            termE = g * [sin(phi); cos(phi)*sin(theta); 0];
            
            x_y_gamma_ddot = termB \ (termE - termA - termC - termD) - b * [xdot; ydot; gammadot];
            qdot = [xdot; ydot; gammadot; x_y_gamma_ddot]; 
            
            
        end
        
       function qdot = double_integrator_dynamics(app, xddot, yddot)
            qdot = zeros(4,1); 
            
            % double integrator
            qdot(1) = app.q(3);    % x_dot   
            qdot(3) = 9.8 * xddot; % x_double_dot
            qdot(2) = app.q(4);    % y_dot
            qdot(4) = 9.8 * yddot;  % y_double_dot
        end


        function rgb = hex2rgb(app, hexString)
	        % given function for converting hex colors to rgb
            if size(hexString,2) ~= 6
		        error('invalid input: not 6 characters');
	        else
		        r = double(hex2dec(hexString(1:2)))/255;
		        g = double(hex2dec(hexString(3:4)))/255;
		        b = double(hex2dec(hexString(5:6)))/255;
		        rgb = [r, g, b];
	        end
        end
        
        function BeginTest(app)
            app.event_start = tic;
            app.q = zeros(6,1); 
            app.time_data = zeros(1,4000);
            app.state_data = zeros(6,4000);
            app.index=0;
            return;
        end
        
        % function that moves star for getting the hang of the sim
        function Practice(app)
            set(app.pstar, 'xdata', rand*6-3, 'ydata', rand*6-3)
            app.move_timer=tic;            
        end


        % function that updates axes
        function updateSim(app)       
            % storing accelerometer data
            import matlab.net.*
            import matlab.net.http.*
            % debugging timer, left in for potential later use
            loopRate = tic;
            %%% time remaining display %%%
            if isequal(app.app_state,'idle')
                time_display=duration(0,0,0);
                app.TimeRemainingTextArea.Value=char(time_display);
            elseif isequal(app.app_state,'countdown')
                countdown_display=app.countdown-toc(app.event_start);
                countdown_display=round(countdown_display);
                countdown_display=int2str(countdown_display);
                if isequal('0', countdown_display)
                    countdown_display='GO!';
                end
                app.ptext.String=countdown_display;
                time_display=duration(0,0,app.event_duration);
                app.TimeRemainingTextArea.Value=char(time_display);
            else
                remaining=app.event_duration-toc(app.event_start);
                time_display=duration(0,0,remaining);
                app.TimeRemainingTextArea.Value=char(time_display);
            end

            %%% calculations %%%
            % get orientation from phone sensors
            % orient = app.m.Orientation; % [rand*180-90 rand*100-50 rand*100-50]
            % angVel = app.m.AngularVelocity; % [rand*6-3 x3]
            % 
            % if ~isempty(orient) & ~isempty(angVel)
            %     theta = -deg2rad(orient(2));
            %     phi = deg2rad(orient(3)); 
            %     thetadot = -deg2rad(angVel(2));
            %     phidot = deg2rad(angVel(3));
            % else
            %    disp("orient or angvel unavailable")
            %    return;
            % end
            
            %app.time_benchmark(1,app.index)=toc(loopRate); 
            
            
            % more accurate
            % odefun = @(tsim, q) dynamics(app, q, theta, phi, thetadot,phidot,0,0); 
            % [tout, qout] = ode45(odefun, [0, app.dt], app.q); 
            
            % this will fetch the data from the server, in the
            % format of a struct with variables X, Y, and Z, in units of G.
            % On Surface, axes are oriented such that the z axis is coming
            % toward the viewer out of the screen, the x axis is
            % pointing toward the right of the screen, and the y axis is
            % pointing toward the top of the screen
            % Request data from C# server
            [response] = send(RequestMessage, URI("http://localhost:5000"));

            % Convert data from JSON to MATLAB struct
            app.tab_orient = jsondecode(convertCharsToStrings(native2unicode(response.Body.Data)));

            % trying double integrator
            odefun=@(tsim,q) double_integrator_dynamics(app, app.tab_orient.X, app.tab_orient.Y);
            [tout, qout] = ode45(odefun, [0, app.dt], app.q); 
            app.q = qout(end,:)';
            
            % applying bounds
            x_init=app.q(1);
            y_init=app.q(2);
            app.q(1)=min(5,app.q(1));
            app.q(1)=max(app.q(1),-5);
            app.q(2)=min(5, app.q(2));
            app.q(2)=max(app.q(2),-5);
            
            % if x or y were changed by the bounds, reduce speed. 
            if ~isequal(x_init, app.q(1)) | ~isequal(y_init,app.q(2))
                app.q(3)=app.q(3)*-0.5;
                app.q(4)=app.q(4)*-0.5;
            end
            
            %app.time_benchmark(2,app.index)=toc(loopRate);
            
            % log data
            % preallocate
            app.state_data(:,app.index) = app.q;
            app.time_data(app.index) =  toc(app.event_start);
             
            %%% graph next step %%%
            tail_size=15;
            xlog = app.state_data(1,max(1,(app.index-tail_size)):app.index);
            ylog = app.state_data(2,max(1,(app.index-tail_size)):app.index);
            
            % updating plots
            set(app.ptrail, 'xdata',xlog,'ydata',ylog);            
            set(app.pball, 'xdata',app.q(1), 'ydata', app.q(2));
            drawnow limitrate

            % log duration of rendering current frame
            %app.time_benchmark(3,app.index)=toc(loopRate);

            %%% checking for ending conditions %%%
            % end of the countdown state
            if toc(app.event_start)>app.countdown & isequal(app.app_state, 'countdown')
                app.ptext.String='';
                app.app_state=app.next_state;
                app.next_state='idle';
                BeginTest(app);
                % after changing state, are we in practice mode?
                if isequal(app.app_state, 'practice')
                    Practice(app)
                end
                app.index=app.index+1;
                return;
            end
            
            % checking whether current event needs to end
            if toc(app.event_start)>app.event_duration & ~isequal(app.app_state, 'idle')
                EndStep(app);
                return;
            end

            % moving star every <practice_interval> sec
            if isequal(app.app_state, 'practice') & toc(app.move_timer)>app.practice_interval
                Practice(app)
                app.move_timer=tic;
            end
            
            %app.time_benchmark(4,app.index)=toc(loopRate);   

            % if(rem(app.index,50)==0)
            %     disp("time check!")
            %     range=(app.index-40):app.index;
            %     sample_period=app.time_benchmark(:, range);
            %     disp(mean(sample_period,2));
            % end
            
            % if(rem(app.index,200)==0)
            %     disp("clearing logs!")
            %     discardlogs(app.m);
            %     app.m.SampleRate = 10; 
            %     app.m.AngularVelocitySensorEnabled = 1;
            %     app.m.OrientationSensorEnabled = 1;
            %     app.m.Logging=1;
            % end

            % incrementing
            app.index=app.index+1;
        end
        
        % function that saves the results of a test run for later
        % processing
        function SaveResults(app, saveThisRun)
            if(~saveThisRun)
                app.app_state='idle';
                return
            end
            xlog = app.state_data(1,:);
            ylog = app.state_data(2,:);

            %rmse score, basically our measure of test performance
            dist=sqrt(xlog.^2+ylog.^2);
            n=size(dist);
            n=n(2); 
            rmse=sqrt(sum(dist.^2)/n);
            
            %another metric, per-step RMSE, could be useful
            %(assumes expected value is previous point)
            diffX=diff(xlog);
            diffY=diff(ylog);
            stepDist=hypot(diffX,diffY);
            stepDev=sqrt(sum(stepDist)/n);
            
            finalsave=[app.time_data;app.state_data];

            % file-writing garbage
            [file,path]=uiputfile('.\\results\\*.txt');
            if all([file~=0, path~=0])
                filename=fullfile(path,file);
                writematrix(finalsave, filename)
            end
            app.app_state='idle';     
        end
        
        % function that handles tasks for the end of an event
        function EndStep(app)
            % pause timer for data collection
            running=get(app.t1,'Running');
            if isequal(running, 'on')
                stop(app.t1);
            end
            
            
            % creating popups for process ends
            if(isequal(app.app_state,'test'))
                % display for test run completion
                message="In the following dialogue, please enter your name and the date in the format [your_name]DDMMYY, then click Save.";
                title="Test Run Complete!";
                uialert(app.UIFigure, message,title, ...
                    'Icon', 'success', ...
                    'CloseFcn',	@(~,~)SaveResults(app, strcmp(app.app_state, 'test')));
            elseif(isequal(app.app_state,'practice'))
                % display for practice run completion
                message="Now you're ready for the real thing!";
                title="Practice Run Complete!";
                uialert(app.UIFigure, message,title, ...
                    'Icon', 'success');
                
                % going back to idle
                app.app_state='idle';
            end
            
            disp(app.app_state)
            % display completion
            %app.app_state='idle'
            while ~isequal(app.app_state,'idle')
                pause(.001)
            end
            
            % re-initializing where necessary
            app.q = zeros(6,1); 
            app.time_data = 0;
            app.state_data = app.q;
            set(app.pstar, 'xdata', 0, 'ydata', 0)
            %if not running, resume t1
            running=get(app.t1,'Running');
            if isequal(running, 'off')
                start(app.t1);
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % this enables us to access the data from the server
            import matlab.net.*
            import matlab.net.http.*

            % connect to phone sensors
            % if ~exist('app.m','var')
            %     try
            %         app.m = mobiledev;
            %     catch
            %         uialert(app.UIFigure, 'Exiting App...', 'Failed to Connect to Device')
            %         pause(2)
            %         app.delete
            %         return
            %     end
            % end
            
            % this is the rate at which frames will be rendered
            sampleRate = 25; 
            app.dt =round(1/sampleRate, 3); 
            
            % this is the rate that mobiledev is sampling the phone's
            % sensors
            % app.m.SampleRate = 10; 
            % app.m.AngularVelocitySensorEnabled = 1;
            % app.m.OrientationSensorEnabled = 1;
            
            % begin logging data from phone sensors
            % disp('Connecting to device...')
            % while isempty(app.m.Orientation)
            %     app.m.Logging = 1;
            % end
            % disp('Connected!')
            
            % set up props with initial values
            app.event_start = tic;
            app.q = zeros(4,1); 
            app.time_data = zeros(1,4000);
            app.state_data = zeros(4,4000);
            app.app_state='idle';
            app.next_state='idle';
            app.countdown=3.4; 
            app.time_benchmark=zeros(4,4000);
            app.practice_interval=20;
            app.index=1;
            app.ball_mass=0.5;
           
           % setup for plotting, so that we don't run the scatter() command
           % every frame
            app.pstar= scatter(app.UIAxes,0,0,1500,hex2rgb(app,'31ab1b'),'filled','pentagram') ;
            hold(app.UIAxes, "on")
            app.ptrail=scatter(app.UIAxes,[],[], 10, hex2rgb(app,'bbbbbb'), 'filled' );
            app.pball= scatter(app.UIAxes, [], [], 400, hex2rgb(app,'046ebf'),'filled');
            app.ptext=text(app.UIAxes, 0,0, '', 'FontSize', 40, 'Color','r', FontWeight='bold', HorizontalAlignment='center');
            xlim(app.UIAxes, [-5, 5])
            ylim(app.UIAxes, [-5,5])
               
            
            % set up frame refresher timer
            app.t1=timer('ExecutionMode', 'fixedRate',...
                         'BusyMode', 'drop', ...
                         'Period',app.dt, ...
                         'TimerFcn', @(~,~)updateSim(app));
            start(app.t1);
            
        end

        % Button pushed function: BeginTestButton
        function BeginTestButtonPushed(app, event)
            app.app_state='countdown';
            app.next_state='test';
            % this is intended to be 2:00 for the actual tests
            app.event_duration=10; % records state data for a set duration, then prints score
            app.event_start=tic;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % time benchmark print
            disp(mean(app.time_benchmark));
            % closing out all ongoing data structures
            if exist('app.m')
                app.m.Logging = 0; 
            end
            
            
            %timer cleanup
            if (size(timerfind)>0)
                %clear
                stop(timerfind)
                delete(timerfind)
                
            end
            
            
            delete(app)
            pause(1)
            disp("buh?")
        end

        % Button pushed function: BeginPracticeButton
        function BeginPracticeButtonPushed(app, event)
            app.app_state='countdown';
            app.next_state='practice'; 
            app.event_duration=90; 
            app.event_start=tic;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.BeginTestButton = uibutton(app.UIFigure, 'push');
            app.BeginTestButton.ButtonPushedFcn = createCallbackFcn(app, @BeginTestButtonPushed, true);
            app.BeginTestButton.Text = 'Begin Test';
            app.BeginPracticeButton = uibutton(app.UIFigure, 'push');
            app.BeginPracticeButton.ButtonPushedFcn = createCallbackFcn(app, @BeginPracticeButtonPushed, true);
            app.BeginPracticeButton.Text = 'Begin Practice';
            app.DampingSliderLabel = uilabel(app.UIFigure);
            app.DampingSliderLabel.Text = 'Damping';
            app.DampingSlider = uislider(app.UIFigure);
            app.DampingSlider.Limits = [0.8 1.5];
            app.DampingSlider.MajorTicks = [0.8 1 1.25 1.5];
            app.DampingSlider.MinorTicks = [0.8 1 1.25 1.5];
            app.DampingSlider.Value = 0.8;
            app.TimeRemainingTextAreaLabel = uilabel(app.UIFigure);
            app.TimeRemainingTextAreaLabel.Text = 'Time Remaining:';
            app.TimeRemainingTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = experiment_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end